classdef MontageSetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SavetoExcelMenu                 matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        NewWiringConfigMenu             matlab.ui.container.Menu
        LoadWiringConfigMenu            matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ApplytoCentralPanel             matlab.ui.container.Panel
        AllButton                       matlab.ui.control.Button
        CurrentTableButton              matlab.ui.control.Button
        EmptyChannelHandlingPanel       matlab.ui.container.Panel
        DropDown                        matlab.ui.control.DropDown
        RecordEmptyChannelsCheckBox     matlab.ui.control.CheckBox
        SFDropDown                      matlab.ui.control.DropDown
        SFDropDownLabel                 matlab.ui.control.Label
        SRDropDown                      matlab.ui.control.DropDown
        SRDropDownLabel                 matlab.ui.control.Label
        AddEditArrayPanel               matlab.ui.container.Panel
        SetasGNDButton                  matlab.ui.control.Button
        SetasCOMButton                  matlab.ui.control.Button
        TBarDropDown                    matlab.ui.control.DropDown
        TBarDropDownLabel               matlab.ui.control.Label
        EIDEditField                    matlab.ui.control.NumericEditField
        EIDEditFieldLabel               matlab.ui.control.Label
        PinIDEditField                  matlab.ui.control.EditField
        PinIDEditFieldLabel             matlab.ui.control.Label
        SpikeFilterDropDown             matlab.ui.control.DropDown
        SpikeFilterDropDownLabel        matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        TypeDropDownLabel               matlab.ui.control.Label
        DefaultButton                   matlab.ui.control.Button
        SamplingFilterDropDown          matlab.ui.control.DropDown
        SamplingFilterDropDownLabel     matlab.ui.control.Label
        SamplingRateDropDown            matlab.ui.control.DropDown
        SamplingRateDropDownLabel       matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.Button
        NumberofContactsEditField       matlab.ui.control.NumericEditField
        NumberofContactsEditFieldLabel  matlab.ui.control.Label
        ArrayIDEditField                matlab.ui.control.EditField
        ArrayIDEditFieldLabel           matlab.ui.control.Label
        PatientIDEditField              matlab.ui.control.EditField
        PatientIDLabel                  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        NSP1Tab                         matlab.ui.container.Tab
        NSP1Table                       matlab.ui.control.Table
        NSP2Tab                         matlab.ui.container.Tab
        NSP2Table                       matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Indices
        NihonKohdenTBarLabels = {'A','B','C','D'};
        ElectrodesPerTBar = 64;
        BlackrockNSPLabels = {'NSP1','NSP2'}
        ElectrodesPerNSP = 256;
        activeEmptyChannels = cell(2,1);
        TableHeaders = {'TBarPinID','ElectrodeID','ChannelLabel','Notes','SamplingRate','SamplingFilter','SpikeFilter'};
        SamplingRateHash = {'None',0;'500Hz',1;'1kHz',2;'2kHz',3;'10kHz',4;'30kHz',5};
        FilterHash = {'None',0;'750Hz Highpass',1;'250Hz Highpass',2;'100Hz Highpass',3;'50Hz Lowpass',4;'125Hz Lowpass',5;...
            '250Hz Lowpass',6;'500Hz Lowpass',7;'150Hz Lowpass',8;'10Hz-250Hz Bandpass',9;'2.5kHz Lowpass',10;...
            '2kHz Lowpass',11;'250Hz-5kHz Bandpass',12};
        
        NICPortNames = {'NSP1','NSP2'};
        NSPAddress
    end
    
    properties (Access = private)
        CableConfigurationApp
    end
    
    methods (Access = public)
        
        function updateCurrentTable(app)
            inc = app.EIDEditField.Value;
            noc = app.NumberofContactsEditField.Value;

            % Generate Contact Labels
            al = app.ArrayIDEditField.Value;
            if ~isempty(regexp(al,'\d$','once'))
                al = [al '-'];
            end
            if ceil(log10(abs(noc)+1))==3
                contactLabels = [repmat(app.TypeDropDown.Value,[noc,1]) repmat(al,[noc,1]) reshape(sprintf('%03d',1:noc),[3,noc])'];
            else
                contactLabels = [repmat(app.TypeDropDown.Value,[noc,1]) repmat(al,[noc,1]) reshape(sprintf('%02d',1:noc),[2,noc])'];
            end

            % Get Table Handle
            tableH = findall(app.([app.TabGroup.SelectedTab.Title,'Table']));
            % Update Table
            ind = find(tableH.Data.ElectrodeID==inc);
            tableH.Data.ChannelLabel(ind:ind+noc-1) = cellstr(contactLabels);
            tableH.Data.SamplingRate(ind:ind+noc-1) = cellstr(repmat(app.SamplingRateDropDown.Value,[noc,1]));
            tableH.Data.SamplingFilter(ind:ind+noc-1) = cellstr(repmat(app.SamplingFilterDropDown.Value,[noc,1]));
            if strcmp(app.SpikeFilterDropDown.Enable,'on') == 1
                tableH.Data.SpikeFilter(ind:ind+noc-1) = cellstr(repmat(app.SpikeFilterDropDown.Value,[noc,1]));
            end
            app.EIDEditField.Value = tableH.Data.ElectrodeID(mod(ind+noc,app.ElectrodesPerNSP+1));
            delete(tableH)
            EIDEditFieldValueChanged(app);
        end
        
        function tableCellEdit(app,indices)
            firstRow = indices(1,1);
            firstColumn = indices(1,2);
            newData = event.NewData;
            if firstColumn==3
                if ~contains(newData,'empty')
                    if ~isempty(regexp(newData,'[a-zA-Z]$','once'))
                        newData = sprintf('%s%02d',newData,1);
                    elseif ~isempty(regexp(newData,'[a-zA-Z]\d{1}$','once'))
                        tmp = regexp(newData,'\w*(\d{1})$','tokens');
                        tmp = str2double(cell2mat(vertcat(tmp{:})));
                        newData = sprintf('%s%02d',newData(1:end-1),tmp);
                    end
                end
            end
            %Get Table Handle
            tableH = findall(app.([app.TabGroup.SelectedTab.Title,'Table']));
            tableH.Data(firstRow,firstColumn) = cellstr(newData);
            delete(tableH)
        end
        
        function tableCellSelection(app,indices)
            firstRow = indices(1,1);
            lastRow = indices(end,1);
            firstColumn = indices(1,2);
            app.Indices = indices;
            %Get Table Handle
            tableH = findall(app.([app.TabGroup.SelectedTab.Title,'Table']));

            app.EIDEditField.Value = tableH.Data.ElectrodeID(firstRow);
            app.PinIDEditField.Value = tableH.Data.TBarPinID{firstRow};
            app.TBarDropDown.Value = regexp(tableH.Data.TBarPinID{firstRow},'[A-Z]','match');
            if firstColumn==3
                nRows = length(firstRow:lastRow);
                sr = cellstr(repmat(tableH.Data.SamplingRate(firstRow),[nRows,1]));
                sf = cellstr(repmat(tableH.Data.SamplingFilter(firstRow),[nRows,1]));
                spksf = cellstr(repmat(tableH.Data.SpikeFilter(firstRow),[nRows,1]));
                if ~contains(tableH.Data.ChannelLabel(firstRow),'empty')
                    label = cell2mat(tableH.Data.ChannelLabel(firstRow));
                    tmp = regexp(label,'([\w-]+?)(\d{2,3})$','tokens');
                    tmp = vertcat(tmp{:});
                    [arrayID,num] = tmp{:};
                    num = str2double(num);
                    if ceil(log10(abs(nRows)+1))==3
                        contactLabels = [repmat(arrayID,[nRows,1]) reshape(sprintf('%03d',num:num+nRows-1),[3,nRows])'];
                    else
                        contactLabels = [repmat(arrayID,[nRows,1]) reshape(sprintf('%02d',num:num+nRows-1),[2,nRows])'];
                    end
                    contactLabels = cellstr(contactLabels);
                    tableH.Data.ChannelLabel(firstRow:lastRow) = contactLabels;
                    tableH.Data.SamplingRate(firstRow:lastRow) = sr;
                    tableH.Data.SamplingFilter(firstRow:lastRow) = sf;
                    tableH.Data.SpikeFilter(firstRow:lastRow) = spksf;
                end
                emptyChannelHandling(app)
            end
            delete(tableH)
        end

        function emptyChannelHandling(app)
            for i = 1:numel(app.BlackrockNSPLabels)
                % Set All Empty Channels in Table to Default Values
                % Get Table Handle
                tableH = findall(app.([app.BlackrockNSPLabels{i},'Table']));
                indexNSP = find(~cellfun(@isempty,regexp(tableH.Data.ChannelLabel,'^empty')));
                sr = cell(length(indexNSP),1);
                sf = cell(length(indexNSP),1);
                sr(:) = app.SamplingRateHash(1,1);
                sf(:) = app.FilterHash(1,1);
                spkf = cellstr(blanks(length(indexNSP))');
                tableH.Data.SamplingRate(indexNSP) = sr;
                tableH.Data.SamplingFilter(indexNSP) = sf;
                tableH.Data.SpikeFilter(indexNSP) = spkf;

                % Set All Empty Channels in Table to Desired Values
                if app.RecordEmptyChannelsCheckBox.Value == 1
                    switch app.DropDown.Value
                        case 'All'
                            indexNSP = find(~cellfun(@isempty,regexp(tableH.Data.ChannelLabel,'^empty')));
                            app.activeEmptyChannels{i} = indexNSP;

                        case 'One'
                            indexNSP = find(~cellfun(@isempty,regexp(tableH.Data.ChannelLabel,'^empty')),1,'last');
                            app.activeEmptyChannels{i} = indexNSP;

                        case 'One per TBar'
                            indAmp = arrayfun(@(x) find(contains(tableH.Data.TBarPinID,x)),'A':'D','Uni',0);
                            indEmpty = find(~cellfun(@isempty,regexp(tableH.Data.ChannelLabel,'^empty')));
                            tmp = cellfun(@(x) intersect(x,indEmpty),indAmp,'Uni',0);
                            tmp = tmp(~cellfun(@isempty,tmp));
                            indexNSP = cell2mat(cellfun(@(x) x(end),tmp,'Uni',0));
                            app.activeEmptyChannels{i} = indexNSP;
                    end

                    if ~isempty(app.activeEmptyChannels{i})
                        sr = cellstr(repmat(app.SRDropDown.Value,[length(app.activeEmptyChannels{i}),1]));
                        sf = cellstr(repmat(app.SFDropDown.Value,[length(app.activeEmptyChannels{i}),1]));
                        tableH.Data.SamplingRate(app.activeEmptyChannels{i}) = sr;
                        tableH.Data.SamplingFilter(app.activeEmptyChannels{i}) = sf;
                    end

                end
                delete(tableH)
            end
        end
        
        function reorderElectrodeID(app,reorderingMatrix)
            for i = 1:size(reorderingMatrix,2)
                app.([app.BlackrockNSPLabels{i},'Table']).Data.ElectrodeID = app.([app.BlackrockNSPLabels{i},'Table']).Data.ElectrodeID(reorderingMatrix(:,i));
            end
            emptyChannelHandling(app)
            EIDEditFieldValueChanged(app);
        end

        function applyToCentral(app,tableH,nspIndex)
            tmpChannelNames = tableH.Data.ChannelLabel;
            dash = cellstr(repmat('-',[256,1]));
            tmpElectrodeIDs = cellstr(num2str(tableH.Data.ElectrodeID,'%03d'));
            ChannelLabels = cellfun(@(x,y,z) [x,y,z],tmpChannelNames,dash,tmpElectrodeIDs,'Uni',0);
            
            ChannelSamplingRate = tableH.Data.SamplingRate;
            ChannelSamplingFilter = tableH.Data.SamplingFilter;
            ChannelSpikeFilter = tableH.Data.SpikeFilter;
            
            sr = zeros(length(ChannelSamplingRate),1);
            sf = zeros(length(ChannelSamplingFilter),1);
            spksf = nan(length(ChannelSpikeFilter),1);
            for i=1:length(sr)
                sr(i) = app.SamplingRateHash{strcmp(app.SamplingRateHash(:,1),ChannelSamplingRate{i}),2};
                sf(i) = app.FilterHash{strcmp(app.FilterHash(:,1),ChannelSamplingFilter{i}),2};
                if isempty(ChannelSpikeFilter{i})
                    continue
                else
                    spksf(i) = app.FilterHash{strcmp(app.FilterHash(:,1),ChannelSpikeFilter{i}),2};
                end
            end
            
            try
                cbmex('open','central-addr',app.NSPAddress{nspIndex},'instance',nspIndex-1)
            catch
                if nspIndex==1
                    uialert(app.UIFigure,'Please Open an Instance of Central and Try Again','Cental Not Open')
                else
                    uialert(app.UIFigure,'To upload the channel data for NSP2, please open the second instance of Central and try again','Second Cental Instance Not Open')
                end
                return
            end
            
            for i = 1:256
                if isnan(spksf(i))
                    cbmex('config',tableH.Data.ElectrodeID(i),'smpgroup',sr(i),'smpfilter',sf(i),'instance',nspIndex-1)
                else
                    cbmex('config',tableH.Data.ElectrodeID(i),'smpgroup',sr(i),'smpfilter',sf(i),'spkfilter',spksf(i),'instance',nspIndex-1)
%                     cbmex('config',table.ElectrodeID(i),'smpgroup',sr(i),'smpfilter',sf(i),'spkextract',1,'spkfilter',spksf(i),'instance',tab-1)
                end
            end
            cbmex('chanlabel',tableH.Data.ElectrodeID,ChannelLabels,'instance',nspIndex-1)
            delete(tableH)
            pause(2)
            cbmex('close')
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,'center')
            
            tmp = arrayfun(@(x) sprintf('%02d',x),1:app.ElectrodesPerTBar,'Uni',0);
            TBarPinID = {};
            for i = 1:numel(app.NihonKohdenTBarLabels)
                TBarPinID = [TBarPinID cellfun(@(x) [app.NihonKohdenTBarLabels{i} x],tmp,'Uni',0)];
            end
            TBarPinID = TBarPinID';

            ElectrodeID = (1:app.ElectrodesPerNSP)';
            ChannelLabel = cellstr(repmat('empty',[length(ElectrodeID),1]));
            Notes = cellstr(blanks(length(ElectrodeID))');
            SamplingRate = cellstr(blanks(length(ElectrodeID))');
            SamplingFilter = cellstr(blanks(length(ElectrodeID))');
            SpkFilter = cellstr(blanks(length(ElectrodeID))');
            s = uistyle('HorizontalAlignment','center');
            for i = 1:numel(app.BlackrockNSPLabels)
                app.([app.BlackrockNSPLabels{i},'Table']).Data = table(TBarPinID,ElectrodeID,ChannelLabel,Notes,SamplingRate,SamplingFilter,SpkFilter,'VariableNames',app.TableHeaders);
                app.([app.BlackrockNSPLabels{i},'Table']).ColumnName = app.TableHeaders;
                addStyle(app.([app.BlackrockNSPLabels{i},'Table']),s,'column',[1 2])
            end

            app.NSPAddress = getIPAddressesFromPortNames(app.NICPortNames);

            emptyChannelHandling(app)
            app.UIFigure.Visible = 'on';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {950, 950};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {204, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: SavetoExcelMenu
        function SavetoExcelButtonPushed(app, event)
            [file,path] = uiputfile([app.PatientIDEditField.Value,'_montage.xlsx']);
            figure(app.UIFigure)
            if file==0
                return
            end
            
            for i = 1:2
                writetable(app.NSPTable{i},fullfile(path,file),'Sheet',i)
            end
        end

        % Menu selected function: LoadMenu
        function LoadButtonPushed(app, event)
            [file,dir]=uigetfile({'*.xlsx','Excel Files(*.xlsx'},'Select File');
            figure(app.UIFigure)
            if file==0
                return
            end
            [~,filename]=fileparts(fullfile(dir,file));
            app.PatientIDEditField.Value = erase(filename,'_montage');
            app.SavetoExcelMenu.Enable = 1;
            
            for i = 1:2
                app.NSPTable{i} = readtable(fullfile(dir,file),'Sheet',i);
                for j = 4:7
                    if ~iscell(app.NSPTable{i}.(app.TableHeaders{j}))
                        app.NSPTable{i}.(app.TableHeaders{j}) = cellstr(blanks(size(app.NSPTable{i},1))');
                    end
                end
            end
            
            emptyChannelHandling(app)
        end

        % Button pushed function: CurrentTableButton
        function CurrentTableButtonPushed(app, event)
            if ~ispc
                uialert(app.UIFigure,'Button does not work for UNIX Systems.','UNIX Incompatibility')
                return
            end
            
            
            tableH = findall(app.([app.TabGroup.SelectedTab.Title,'Table']));
            nspIndex = str2double(app.TabGroup.SelectedTab.Title(end));
            applyToCentral(app,tableH,nspIndex)
            delete(tableH)
        end

        % Button pushed function: AllButton
        function AllButtonPushed(app, event)
            if ~ispc
                uialert(app.UIFigure,'Button does not work for UNIX Systems.','UNIX Incompatibility')
                return
            end
            
            for i = 1:numel(app.BlackrockNSPLabels)
                tableH = findall(app.([app.BlackrockNSPLabels{i},'Table']));
                applyToCentral(app,tableH,nspIndex)
                delete(tableH)
            end
        end

        % Value changed function: RecordEmptyChannelsCheckBox
        function RecordEmptyChannelsCheckBoxValueChanged(app, event)
            app.DropDown.Enable = app.RecordEmptyChannelsCheckBox.Value;
            app.SRDropDown.Enable = app.RecordEmptyChannelsCheckBox.Value;
            app.SFDropDown.Enable = app.RecordEmptyChannelsCheckBox.Value;
            if ~app.RecordEmptyChannelsCheckBox.Value
                for i = 1:numel(app.BlackrockNSPLabels)
                    app.activeEmptyChannels{i} = [];
                end
            end
            emptyChannelHandling(app)
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            emptyChannelHandling(app)
        end

        % Value changed function: SRDropDown
        function SRDropDownValueChanged(app, event)
            emptyChannelHandling(app)
        end

        % Value changed function: SFDropDown
        function SFDropDownValueChanged(app, event)
            emptyChannelHandling(app)
        end

        % Value changed function: TBarDropDown
        function TBarDropDownValueChanged(app, event)
            tableH = findall(app.([app.TabGroup.SelectedTab.Title,'Table']));
            ind = find(contains(tableH.Data.TBarPinID,app.TBarDropDown.Value),1);
            app.PinIDEditField.Value = tableH.Data.TBarPinID{ind};
            app.EIDEditField.Value = tableH.Data.ElectrodeID(ind);
            delete(tableH)
        end

        % Value changed function: EIDEditField
        function EIDEditFieldValueChanged(app, event)
            tableH = findall(app.([app.TabGroup.SelectedTab.Title,'Table']));
            ind = find(tableH.Data.ElectrodeID==app.EIDEditField.Value);
            app.PinIDEditField.Value = tableH.Data.TBarPinID{ind};
            app.TBarDropDown.Value = regexp(tableH.Data.TBarPinID{ind},'[A-Z]','match');
            delete(tableH)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            currentDir = pwd;
            cd(pathToMLAPP)
            if ~isempty(app.CableConfigurationApp) && isvalid(app.CableConfigurationApp)
                delete(app.CableConfigurationApp)
            end
            delete(app)
            cd(currentDir);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            updateCurrentTable(app)
            emptyChannelHandling(app)
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            rows = unique(app.Indices(:,1));
            ChannelLabel = cellstr(repmat('empty',[length(rows),1]));
            Notes = cellstr(blanks(length(rows))');
            % Get Table Handle
            tableH = findall(app.([app.TabGroup.SelectedTab.Title,'Table']));
            
            tableH.Data.ChannelLabel(rows) = ChannelLabel;
            tableH.Data.Notes(rows) = Notes;
            
            delete(tableH)
            emptyChannelHandling(app)
        end

        % Cell edit callback: NSP1Table, NSP2Table
        function TableCellEditEvent(app, event)
            indices = event.Indices;
            tableCellEdit(app,indices)
            emptyChannelHandling(app)
        end

        % Cell selection callback: NSP1Table, NSP2Table
        function TableCellSelectionEvent(app, event)
            indices = event.Indices;
            if indices(1,1)==1 && indices(size(indices,1),1)==256
                if ismember(indices(1,2),[1,2])==1
                    tableH = findall(app.([app.TabGroup.SelectedTab.Title,'Table']));
                    tableH.Data = sortrows(tableH.Data,app.TableHeaders{indices(1,2)});
                    delete(tableH)
                    emptyChannelHandling(app)
                else
                    return
                end
            end
            tableCellSelection(app,indices)
        end

        % Menu selected function: NewWiringConfigMenu
        function ConfigureCablingButtonPushed(app, event)
            app.NewWiringConfigMenu.Enable = 'off';
            ElectrodeID = (1:app.ElectrodesPerNSP)';
            for i = 1:numel(app.BlackrockNSPLabels)
                app.([app.BlackrockNSPLabels{i},'Table']).Data.ElectrodeID = ElectrodeID;
            end
            app.CableConfigurationApp = MontageSetupWizard(app);
        end

        % Value changed function: PatientIDEditField
        function PatientIDEditFieldValueChanged(app, event)
            if ~isempty(app.PatientIDEditField.Value)
                app.SavetoExcelMenu.Enable = 1;
            else
                app.SavetoExcelMenu.Enable = 0;
            end
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            if strcmp(app.TypeDropDown.Value,'m')
                app.SamplingRateDropDown.Value = '30kHz';
                app.SamplingFilterDropDown.Value = 'None';
                app.SpikeFilterDropDown.Value = '250Hz Highpass';
                app.SpikeFilterDropDown.Enable = 1;
            else
                app.SamplingRateDropDown.Value = '2kHz';
                app.SamplingFilterDropDown.Value = '500Hz Lowpass';
                app.SpikeFilterDropDown.Enable = 0;
            end
        end

        % Button pushed function: SetasCOMButton
        function SetasCOMButtonPushed(app, event)
            % Get Table Handle
            tableH = findall(app.([app.TabGroup.SelectedTab.Title,'Table']));

            Labels = tableH.Data.ChannelLabel;
            
            %Remove Previous COM Label if exists
            Labels = regexprep(Labels,'^C','');
            
            %Overwrite GND Label if on Selected Label or Append COM Label 
            if isempty(regexp(Labels{app.Indices(1)},'^Z','once'))
                Labels{app.Indices(1)} = ['C' Labels{app.Indices(1)}];
            else
                Labels{app.Indices(1)} = regexprep(Labels{app.Indices(1)},'^Z','C');
            end
            
            tableH.Data.ChannelLabel = Labels;
            delete(tableH)
            emptyChannelHandling(app)
        end

        % Button pushed function: SetasGNDButton
        function SetasGNDButtonPushed(app, event)
            % Get Table Handle
            tableH = findall(app.([app.TabGroup.SelectedTab.Title,'Table']));
            
            Labels = tableH.Data.ChannelLabel;
            
            %Remove Previous GND Label if exists
            Labels = regexprep(Labels,'^Z','');
            
            %Overwrite COM Label if on Selected Label or Append GND Label 
            if isempty(regexp(Labels{app.Indices(1)},'^C','once'))
                Labels{app.Indices(1)} = ['Z' Labels{app.Indices(1)}];
            else
                Labels{app.Indices(1)} = regexprep(Labels{app.Indices(1)},'^C','Z');
            end
            
            tableH.Data.ChannelLabel = Labels;
            delete(tableH)
            emptyChannelHandling(app)
        end

        % Value changing function: ArrayIDEditField
        function ArrayIDEditFieldValueChanging(app, event)
            changingValue = event.Value;
            if isempty(app.TypeDropDown.Value)
                if length(changingValue)<9
                    app.ArrayIDEditField.FontColor = [0 0 0];
                    app.ArrayIDEditField.BackgroundColor = [1 1 1];
                    app.ArrayIDEditField.Tooltip = '';
                    app.ApplyButton.Enable = 1;
                elseif length(changingValue)<10
                    app.ArrayIDEditField.FontColor = [1.00,0.41,0.16];
                    app.ArrayIDEditField.BackgroundColor = [1 1 1];
                    app.ArrayIDEditField.Tooltip = 'Warning: Array ID may be too long when applied to Blackrock Central';
                    app.ApplyButton.Enable = 1;
                else
                    app.ArrayIDEditField.FontColor = [1 1 1];
                    app.ArrayIDEditField.BackgroundColor = [1 0 0];
                    app.ArrayIDEditField.Tooltip = 'Warning: Array ID is too long for Blackrock Central';
                    app.ApplyButton.Enable = 0;
                end
            else
                if length(changingValue)<8
                    app.ArrayIDEditField.FontColor = [0 0 0];
                    app.ArrayIDEditField.BackgroundColor = [1 1 1];
                    app.ArrayIDEditField.Tooltip = '';
                    app.ApplyButton.Enable = 1;
                elseif length(changingValue)<9
                    app.ArrayIDEditField.FontColor = [1.00,0.41,0.16];
                    app.ArrayIDEditField.BackgroundColor = [1 1 1];
                    app.ArrayIDEditField.Tooltip = 'Warning: Array ID may be too long when applied to Blackrock Central';
                    app.ApplyButton.Enable = 1;
                else
                    app.ArrayIDEditField.FontColor = [1 1 1];
                    app.ArrayIDEditField.BackgroundColor = [1 0 0];
                    app.ArrayIDEditField.Tooltip = 'Warning: Array ID is too long for Blackrock Central';
                    app.ApplyButton.Enable = 0;
                end
            end
        end

        % Value changed function: NumberofContactsEditField
        function NumberofContactsEditFieldValueChanged(app, event)
            ArrayIDEditFieldValueChanging(app,event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Montage Setup';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SavetoExcelMenu = uimenu(app.FileMenu);
            app.SavetoExcelMenu.MenuSelectedFcn = createCallbackFcn(app, @SavetoExcelButtonPushed, true);
            app.SavetoExcelMenu.Enable = 'off';
            app.SavetoExcelMenu.Text = 'Save to Excel';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadMenu.Text = 'Load';
            app.NewWiringConfigMenu = uimenu(app.FileMenu);
            app.NewWiringConfigMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfigureCablingButtonPushed, true);
            app.NewWiringConfigMenu.Separator = 'on';
            app.NewWiringConfigMenu.Text = 'New Wiring Config';
            app.LoadWiringConfigMenu = uimenu(app.FileMenu);
            app.LoadWiringConfigMenu.Text = 'Load Wiring Config';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PatientIDLabel = uilabel(app.LeftPanel);
            app.PatientIDLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.LeftPanel, 'text');
            app.PatientIDEditField.ValueChangedFcn = createCallbackFcn(app, @PatientIDEditFieldValueChanged, true);
            app.AddEditArrayPanel = uipanel(app.LeftPanel);
            app.AddEditArrayPanel.Title = 'Add/Edit Array';
            app.ArrayIDEditFieldLabel = uilabel(app.AddEditArrayPanel);
            app.ArrayIDEditFieldLabel.Text = 'Array ID';
            app.ArrayIDEditField = uieditfield(app.AddEditArrayPanel, 'text');
            app.ArrayIDEditField.ValueChangingFcn = createCallbackFcn(app, @ArrayIDEditFieldValueChanging, true);
            app.NumberofContactsEditFieldLabel = uilabel(app.AddEditArrayPanel);
            app.NumberofContactsEditFieldLabel.Text = 'Number of Contacts';
            app.NumberofContactsEditField = uieditfield(app.AddEditArrayPanel, 'numeric');
            app.NumberofContactsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofContactsEditFieldValueChanged, true);
            app.NumberofContactsEditField.Value = 1;
            app.ApplyButton = uibutton(app.AddEditArrayPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SamplingRateDropDownLabel = uilabel(app.AddEditArrayPanel);
            app.SamplingRateDropDownLabel.Text = 'Sampling Rate';
            app.SamplingRateDropDown = uidropdown(app.AddEditArrayPanel);
            app.SamplingRateDropDown.Items = {'None', '500Hz', '1kHz', '2kHz', '10kHz', '30kHz', ''};
            app.SamplingRateDropDown.Value = '2kHz';
            app.SamplingFilterDropDownLabel = uilabel(app.AddEditArrayPanel);
            app.SamplingFilterDropDownLabel.Text = 'Sampling Filter';
            app.SamplingFilterDropDown = uidropdown(app.AddEditArrayPanel);
            app.SamplingFilterDropDown.Items = {'None', '750Hz Highpass', '250Hz Highpass', '100Hz Highpass', '50Hz Lowpass', '125Hz Lowpass', '250Hz Lowpass', '500Hz Lowpass', '150Hz Lowpass', '10Hz-250Hz Bandpass', '2.5kHz Lowpass', '2kHz Lowpass', '250Hz-5kHz Bandpass'};
            app.SamplingFilterDropDown.Value = '500Hz Lowpass';
            app.DefaultButton = uibutton(app.AddEditArrayPanel, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.TypeDropDownLabel = uilabel(app.AddEditArrayPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.AddEditArrayPanel);
            app.TypeDropDown.Items = {'sEEG', 'Micro', 'Subdural', 'DBS', 'External'};
            app.TypeDropDown.ItemsData = {'', 'm', 'g', 'd', 'x'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = '';
            app.SpikeFilterDropDownLabel = uilabel(app.AddEditArrayPanel);
            app.SpikeFilterDropDownLabel.Text = 'Spike Filter';
            app.SpikeFilterDropDown = uidropdown(app.AddEditArrayPanel);
            app.SpikeFilterDropDown.Items = {'None', '750Hz Highpass', '250Hz Highpass', '100Hz Highpass', '50Hz Lowpass', '125Hz Lowpass', '250Hz Lowpass', '500Hz Lowpass', '150Hz Lowpass', '10Hz-250Hz Bandpass', '2.5kHz Lowpass', '2kHz Lowpass', '250Hz-5kHz Bandpass'};
            app.SpikeFilterDropDown.Enable = 'off';
            app.SpikeFilterDropDown.Value = '250Hz Highpass';
            app.PinIDEditFieldLabel = uilabel(app.AddEditArrayPanel);
            app.PinIDEditFieldLabel.Text = 'Pin ID';
            app.PinIDEditField = uieditfield(app.AddEditArrayPanel, 'text');
            app.PinIDEditField.Editable = 'off';
            app.PinIDEditField.Value = 'A01';
            app.EIDEditFieldLabel = uilabel(app.AddEditArrayPanel);
            app.EIDEditFieldLabel.Text = 'EID';
            app.EIDEditField = uieditfield(app.AddEditArrayPanel, 'numeric');
            app.EIDEditField.Limits = [1 256];
            app.EIDEditField.ValueChangedFcn = createCallbackFcn(app, @EIDEditFieldValueChanged, true);
            app.EIDEditField.Value = 1;
            app.TBarDropDownLabel = uilabel(app.AddEditArrayPanel);
            app.TBarDropDownLabel.Text = 'T-Bar';
            app.TBarDropDown = uidropdown(app.AddEditArrayPanel);
            app.TBarDropDown.Items = {'A', 'B', 'C', 'D'};
            app.TBarDropDown.ValueChangedFcn = createCallbackFcn(app, @TBarDropDownValueChanged, true);
            app.TBarDropDown.Value = 'A';
            app.SetasCOMButton = uibutton(app.AddEditArrayPanel, 'push');
            app.SetasCOMButton.ButtonPushedFcn = createCallbackFcn(app, @SetasCOMButtonPushed, true);
            app.SetasCOMButton.Text = 'Set as COM';
            app.SetasGNDButton = uibutton(app.AddEditArrayPanel, 'push');
            app.SetasGNDButton.ButtonPushedFcn = createCallbackFcn(app, @SetasGNDButtonPushed, true);
            app.SetasGNDButton.Text = 'Set as GND';
            app.EmptyChannelHandlingPanel = uipanel(app.LeftPanel);
            app.EmptyChannelHandlingPanel.Title = 'Empty Channel Handling';
            app.SRDropDownLabel = uilabel(app.EmptyChannelHandlingPanel);
            app.SRDropDownLabel.Enable = 'off';
            app.SRDropDownLabel.Text = 'SR';
            app.SRDropDown = uidropdown(app.EmptyChannelHandlingPanel);
            app.SRDropDown.Items = {'None', '500Hz', '1kHz', '2kHz', '10kHz', '30kHz', ''};
            app.SRDropDown.ValueChangedFcn = createCallbackFcn(app, @SRDropDownValueChanged, true);
            app.SRDropDown.Enable = 'off';
            app.SRDropDown.Value = '2kHz';
            app.SFDropDownLabel = uilabel(app.EmptyChannelHandlingPanel);
            app.SFDropDownLabel.Enable = 'off';
            app.SFDropDownLabel.Text = 'SF';
            app.SFDropDown = uidropdown(app.EmptyChannelHandlingPanel);
            app.SFDropDown.Items = {'None', '750Hz Highpass', '250Hz Highpass', '100Hz Highpass', '50Hz Lowpass', '125Hz Lowpass', '250Hz Lowpass', '500Hz Lowpass', '150Hz Lowpass', '10Hz-250Hz Bandpass', '2.5kHz Lowpass', '2kHz Lowpass', '250Hz-5kHz Bandpass'};
            app.SFDropDown.ValueChangedFcn = createCallbackFcn(app, @SFDropDownValueChanged, true);
            app.SFDropDown.Enable = 'off';
            app.SFDropDown.Value = '500Hz Lowpass';
            app.RecordEmptyChannelsCheckBox = uicheckbox(app.EmptyChannelHandlingPanel);
            app.RecordEmptyChannelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecordEmptyChannelsCheckBoxValueChanged, true);
            app.RecordEmptyChannelsCheckBox.Text = 'Record Empty Channels';
            app.RecordEmptyChannelsCheckBox.Value = true;
            app.DropDown = uidropdown(app.EmptyChannelHandlingPanel);
            app.DropDown.Items = {'All', 'One', 'One per TBar', ''};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'One per TBar';
            app.ApplytoCentralPanel = uipanel(app.LeftPanel);
            app.ApplytoCentralPanel.Title = 'Apply to Central';
            app.CurrentTableButton = uibutton(app.ApplytoCentralPanel, 'push');
            app.CurrentTableButton.ButtonPushedFcn = createCallbackFcn(app, @CurrentTableButtonPushed, true);
            app.CurrentTableButton.Text = 'Current Table';
            app.AllButton = uibutton(app.ApplytoCentralPanel, 'push');
            app.AllButton.ButtonPushedFcn = createCallbackFcn(app, @AllButtonPushed, true);
            app.AllButton.Text = 'All';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.NSP1Tab = uitab(app.TabGroup);
            app.NSP1Tab.Title = 'NSP1';
            app.NSP1Table = uitable(app.NSP1Tab);
            app.NSP1Table.ColumnName = {''};
            app.NSP1Table.RowName = {};
            app.NSP1Table.CellEditCallback = createCallbackFcn(app, @TableCellEditEvent, true);
            app.NSP1Table.CellSelectionCallback = createCallbackFcn(app, @TableCellSelectionEvent, true);
            app.NSP2Tab = uitab(app.TabGroup);
            app.NSP2Tab.Title = 'NSP2';
            app.NSP2Table = uitable(app.NSP2Tab);
            app.NSP2Table.ColumnName = {''};
            app.NSP2Table.RowName = {};
            app.NSP2Table.CellEditCallback = createCallbackFcn(app, @TableCellEditEvent, true);
            app.NSP2Table.CellSelectionCallback = createCallbackFcn(app, @TableCellSelectionEvent, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MontageSetup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end